classdef RobotArmPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WorkSpaceAnalyzerUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        PlotButton                 matlab.ui.control.Button
        L1EditFieldLabel           matlab.ui.control.Label
        L1                         matlab.ui.control.NumericEditField
        L2EditFieldLabel           matlab.ui.control.Label
        L2                         matlab.ui.control.NumericEditField
        L3EditFieldLabel           matlab.ui.control.Label
        L3                         matlab.ui.control.NumericEditField
        Q1minEditFieldLabel        matlab.ui.control.Label
        Q1min                      matlab.ui.control.NumericEditField
        Q1maxEditFieldLabel        matlab.ui.control.Label
        Q1max                      matlab.ui.control.NumericEditField
        Q2minEditFieldLabel        matlab.ui.control.Label
        Q2min                      matlab.ui.control.NumericEditField
        Q2maxEditFieldLabel        matlab.ui.control.Label
        Q2max                      matlab.ui.control.NumericEditField
        Q3minLabel                 matlab.ui.control.Label
        Q3min                      matlab.ui.control.NumericEditField
        Q3maxLabel                 matlab.ui.control.Label
        Q3max                      matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        l1;
        l2;
        l3;
        q1_min;
        q2_min;
        q3_min;
        q1_max;
        q2_max;
        q3_max;
        t1;
        t2;
        t3;
    end


    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        
            l1 = app.L1.Value;
            l2 = app.L2.Value;
            l3 = app.L3.Value;
            q1_min = app.Q1min.Value;
            q2_min = app.Q2min.Value;
            q3_min = app.Q3min.Value;
            q1_max = app.Q1max.Value;
            q2_max = app.Q2max.Value;
            q3_max = app.Q3max.Value;
            %Transform qs into radians
            t1=linspace(q1_min, q1_max, 90)*pi/180;
            t2=linspace(q2_min, q2_max, 90)*pi/180;
            t3=linspace(q3_min, q3_max, 90)*pi/180;

            [q1,q2,q3]=ndgrid(t1,t2,t3);  %Creates the matrices of 200x200x200 for each variable 
xM = l1 * cos(q1) + l2 * cos(q1 + q2) + l3 * cos(q1 + q2 + q3);
yM = l1 * sin(q1) + l2 * sin(q1 + q2) + l3 * sin(q1 + q2 + q3);
plot(app.UIAxes,xM(:),yM(:),'.')
            
        end

        % Value changed function: L1
        function L1ValueChanged(app, event)
          
            
        end

        % Value changed function: L2
        function L2ValueChanged(app, event)
            
        end

        % Value changed function: L3
        function L3ValueChanged(app, event)
            
        end

        % Value changed function: Q1min
        function Q1minValueChanged(app, event)
            
        end

        % Value changed function: Q2min
        function Q2minValueChanged(app, event)
            
        end

        % Value changed function: Q3min
        function Q3minValueChanged(app, event)
      
        end

        % Value changed function: Q1max
        function Q1maxValueChanged(app, event)
            
        end

        % Value changed function: Q2max
        function Q2maxValueChanged(app, event)
            
        end

        % Value changed function: Q3max
        function Q3maxValueChanged(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WorkSpaceAnalyzerUIFigure = uifigure;
            app.WorkSpaceAnalyzerUIFigure.Name = 'WorkSpaceAnalyzer';
            app.UIAxes = uiaxes(app.WorkSpaceAnalyzerUIFigure);
            title(app.UIAxes, 'WorkSpace')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.WorkSpaceAnalyzerUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.L1EditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1 = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.L1.ValueChangedFcn = createCallbackFcn(app, @L1ValueChanged, true);
            app.L2EditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2 = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.L2.ValueChangedFcn = createCallbackFcn(app, @L2ValueChanged, true);
            app.L3EditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3 = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.L3.ValueChangedFcn = createCallbackFcn(app, @L3ValueChanged, true);
            app.Q1minEditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q1minEditFieldLabel.Text = 'Q1 min';
            app.Q1min = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q1min.ValueChangedFcn = createCallbackFcn(app, @Q1minValueChanged, true);
            app.Q1maxEditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q1maxEditFieldLabel.Text = 'Q1 max';
            app.Q1max = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q1max.ValueChangedFcn = createCallbackFcn(app, @Q1maxValueChanged, true);
            app.Q2minEditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q2minEditFieldLabel.Text = 'Q2 min';
            app.Q2min = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q2min.ValueChangedFcn = createCallbackFcn(app, @Q2minValueChanged, true);
            app.Q2maxEditFieldLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q2maxEditFieldLabel.Text = 'Q2 max';
            app.Q2max = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q2max.ValueChangedFcn = createCallbackFcn(app, @Q2maxValueChanged, true);
            app.Q3minLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q3minLabel.Text = 'Q3 min';
            app.Q3min = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q3min.ValueChangedFcn = createCallbackFcn(app, @Q3minValueChanged, true);
            app.Q3maxLabel = uilabel(app.WorkSpaceAnalyzerUIFigure);
            app.Q3maxLabel.Text = 'Q3 max';
            app.Q3max = uieditfield(app.WorkSpaceAnalyzerUIFigure, 'numeric');
            app.Q3max.ValueChangedFcn = createCallbackFcn(app, @Q3maxValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = RobotArmPlot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WorkSpaceAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WorkSpaceAnalyzerUIFigure)
        end
    end
end